classdef CIF_analyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        pHImageLabel                matlab.ui.control.Label
        LoadpHImageButton           matlab.ui.control.Button
        SemanticSegmentationButton  matlab.ui.control.Button
        OverlayImageLabel           matlab.ui.control.Label
        ROIDetectionLabel           matlab.ui.control.Label
        FinalOverlayButton          matlab.ui.control.Button
        ChipTypeDropDownLabel       matlab.ui.control.Label
        ChipTypeDropDown            matlab.ui.control.DropDown
        pHRangeintheDeviceLabel     matlab.ui.control.Label
        pHContoursButton            matlab.ui.control.Button
        CHANGLABUniversityofNotreDameLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        pHHeatmapLabel              matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.EditField
        Label_2                     matlab.ui.control.Label
        CIFAnalyzerLabel            matlab.ui.control.Label
        autoLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        image_name % Name of  of the testing image
        test_image % Test image RGB array
        binary_mask % Machine learning generated binary mask
        ph_value_calc % value for pH calculated by machine learning
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ProgressButtonPushed(app, event)
%             for i =1:10
%                 pause(0.5);
%                 r = randi([-100 100]);
%                 app.Gauge.Value = r;
%                 app.Spinner.Value = r;
%             end
%             app.Gauge.BackgroundColor = [1, 0, 0]; 
%             pause(1);
%             app.Gauge.BackgroundColor= [0, 1, 0]; 
% %             pause(1);
% %             app.Gauge.BackgroundColor = [0, 0, 1];
%             ''
%             
        end

        % Button pushed function: LoadpHImageButton
        function LoadpHImageButtonPushed(app, event)
%             global test_image;
            global filename;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            app.image_name = filename;

            filename = strcat(pathname,filename);
            app.test_image = imread(filename);
            
            % Remove title and axis labels
            title(app.UIAxes_5, []);
            xlabel(app.UIAxes_5, []);
            ylabel(app.UIAxes_5, []);
            
            % Display image and stretch to fill axes
            I = imshow(app.test_image,'Parent',app.UIAxes_5, ...
                'XData', [1 app.UIAxes_5.Position(3)], ...
                'YData', [1 app.UIAxes_5.Position(4)]);
            
            % Remove tick labels
            app.UIAxes_5.XAxis.TickLabels = {};
            app.UIAxes_5.YAxis.TickLabels = {};
            
            % Set limits of axes
            app.UIAxes_5.XLim = [0 I.XData(2)];
            app.UIAxes_5.YLim = [0 I.YData(2)];

        end

        % Button pushed function: SemanticSegmentationButton
        function SemanticSegmentationButtonPushed(app, event)
            % Here the ilastik is called in headless mode to calculate the binary mask
            % of the pH image
            
% 
%             cd 'C:\Program Files\ilastik-1.3.3post3'
%             str_first = 'ilastik.exe --headless --project=C:/Users/Himani Sharma/Desktop/machine learning/3D print/training/1252022/Print_3D.ilp --export_source="Simple Segmentation" --output_format=png --output_filename_format=C:/Users/Himani Sharma/Desktop/machine learning/3D print/{nickname}_{result_type}.png ';
%             str_second = app.image_name;
%             s = ' ';
%             str_final = [str_first,s,str_second];
%             system(str_final)

%             cd 'C:\Users\Himani Sharma\Box\himani (Himani Sharma)\machine learning\Image_Analysis_final_code' 
            a = imread('H12_Simple Segmentation.png');
            a(a(:,:)==2)=0;
            a = a.*255;
            a = imfill(a,'holes');
            mask = bwareaopen(a,55000);
            app.binary_mask = mask;
            % Display the binary masked image and stretch to fill axes
            I = imshow(app.binary_mask,'Parent',app.UIAxes_2, ...
                'XData', [1 app.UIAxes_2.Position(3)], ...
                'YData', [1 app.UIAxes_2.Position(4)]);
            
            % Remove tick labels
            app.UIAxes_2.XAxis.TickLabels = {};
            app.UIAxes_2.YAxis.TickLabels = {};
            
            % Set limits of axes
            app.UIAxes_2.XLim = [0 I.XData(2)];
            app.UIAxes_2.YLim = [0 I.YData(2)];
            
        end

        % Button pushed function: FinalOverlayButton
        function FinalOverlayButtonPushed(app, event)
            I = imshow(labeloverlay(app.test_image,app.binary_mask),'Parent',app.UIAxes_6, ...
                'XData', [1 app.UIAxes_6.Position(3)], ...
                'YData', [1 app.UIAxes_6.Position(4)]);

            % Display the overlay test and binary mask 
            
            % Remove tick labels
            app.UIAxes_6.XAxis.TickLabels = {};
            app.UIAxes_6.YAxis.TickLabels = {};
            
            % Set limits of axes
            app.UIAxes_6.XLim = [0 I.XData(2)];
            app.UIAxes_6.YLim = [0 I.YData(2)];

        end

        % Button pushed function: pHContoursButton
        function pHContoursButtonPushed(app, event)
            %will update it
            switch app.ChipTypeDropDown.Value
                case '3D printed'
                     
                     [image_row_size,image_column_size] = size(app.test_image(:,:,1));
                     
                     Chip_3D_print_cleaned(:,:,1) = app.test_image(:,:,1).*(im2uint8(app.binary_mask)./255);
                     Chip_3D_print_cleaned(:,:,2) = app.test_image(:,:,2).*(im2uint8(app.binary_mask)./255);
                     Chip_3D_print_cleaned(:,:,3) = app.test_image(:,:,3).*(im2uint8(app.binary_mask)./255);

                     
                     Chip_3D_print_cleaned_R = im2double(Chip_3D_print_cleaned(:,:,1));
                     Chip_3D_print_cleaned_G = im2double(Chip_3D_print_cleaned(:,:,2));
                     Chip_3D_print_cleaned_B = im2double(Chip_3D_print_cleaned(:,:,3));
                     
                     Chip_3D_print_cleaned_R_norm = Chip_3D_print_cleaned_R./(Chip_3D_print_cleaned_R + Chip_3D_print_cleaned_G + Chip_3D_print_cleaned_B + 1e-20);
                     Chip_3D_print_cleaned_G_norm = Chip_3D_print_cleaned_G./(Chip_3D_print_cleaned_R + Chip_3D_print_cleaned_G + Chip_3D_print_cleaned_B + 1e-20 );
                     Chip_3D_print_cleaned_B_norm = Chip_3D_print_cleaned_B./(Chip_3D_print_cleaned_R + Chip_3D_print_cleaned_G + Chip_3D_print_cleaned_B + 1e-20 );
                     
                     Regress_coff_acidic = [-1.839584643896252 15.159307147085624 26.706651380276963];
                     Regress_coff_basic = [4.603496721894079 9.803460648487327 15.175133203372402];
                     
                     for i = 1:image_row_size
                         for j =1:image_column_size
                             if Chip_3D_print_cleaned_R_norm(i,j)> 0.49
                                 pH_calc(i,j) = Regress_coff_acidic(1)* Chip_3D_print_cleaned_R_norm(i,j) + Regress_coff_acidic(2)*Chip_3D_print_cleaned_G_norm(i,j) + Regress_coff_acidic(3)*Chip_3D_print_cleaned_B_norm(i,j);
                                 
                             elseif Chip_3D_print_cleaned_R_norm(i,j) ~= 0
                                 pH_calc(i,j) = Regress_coff_basic(1)* Chip_3D_print_cleaned_R_norm(i,j) + Regress_coff_basic(2)*Chip_3D_print_cleaned_G_norm(i,j) + Regress_coff_basic(3)*Chip_3D_print_cleaned_B_norm(i,j);
                             else
                                 pH_calc(i,j) = NaN;
                             end
                         end
                     end
                     app.ph_value_calc = pH_calc;
                                        
                     figure(1)
                     h = imagesc(pH_calc);
                                         
                     % setting alpha values
                     if ndims( pH_calc ) == 2
                         set(h, 'AlphaData', ~isnan(pH_calc))
                     elseif ndims( pH_calc ) == 3
                         set(h, 'AlphaData', ~isnan(pH_calc(:, :, 1)))
                     end
                     if nargout < 1
                         clear h
                     end
                     
                     colormap('jet');
                     c= jet(20);
                     c = flipud(c);
                     colormap(c)
                     colorbar
                     
                     imgName = ['C:\Users\Himani Sharma\Box\himani (Himani Sharma)\machine learning\Image_Analysis_final_code','\pH_final','.png'] ;
                     saveas(gca, imgName);
                     
                     close(figure(1))
                     
                     % reading that as an image
                     ph_final = imread('pH_final.png');
                     
                     I = imshow(ph_final,'Parent',app.UIAxes_4, ...
                         'XData', [1 app.UIAxes_4.Position(3)], ...
                         'YData', [1 app.UIAxes_4.Position(4)]);
                     
                     % Remove tick labels
                     app.UIAxes_4.XAxis.TickLabels = {};
                     app.UIAxes_4.YAxis.TickLabels = {};
                     
                     % Set limits of axes
                     app.UIAxes_4.XLim = [0 I.XData(2)];
                     app.UIAxes_4.YLim = [0 I.YData(2)];
                     
                     app.EditField.Value = max(max(pH_calc));
                     app.EditField_2.Value = min(min(pH_calc));
                     
                     if ( max(max(app.ph_value_calc)) - min(min(app.ph_value_calc)) > 2)
                         app.EditField_3.Value  = 'Good to Go for Further Experiment!';
                     end                                 
                   
                    
                otherwise


            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

              % exportapp(app.UIFigure,'appcontent.jpg')
              % create filename containing the app's figure name (spaces removed)
              % and a datetime stamp in format yymmdd_hhmmss
              %filename = sprintf('%s_%s.pdf',regexprep(app.MyApp.Name,' +',''), datestr(now(),'yymmdd_HHMMSS'));
%               filename = 'CIF_snapshot';
%               % Get the app's path

%               
% %               filepath = fileparts(which([mfilename,'.mlapp']));
% 
%               % Store snapshot

               %exportapp(app.UIFigure, filename)
               robot = java.awt.Robot();
               temp = app.UIFigure.Position; % returns position as [left bottom width height]
               allMonPos = get(0,'MonitorPositions');
               curMon = find(temp(1)<(allMonPos(:,1)+allMonPos(:,3)),1,'first');
               curMonHeight = allMonPos(curMon,4)+1;
               pos = [temp(1) curMonHeight-(temp(2)+temp(4)) temp(3)-1 temp(4)]; % [left top width height].... UL X, UL Y, width, height
               rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
               cap = robot.createScreenCapture(rect);
               % Convert to an RGB image
               rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
               imgData = zeros(cap.getHeight,cap.getWidth,3,'uint8');
               imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
               imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
               imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';
               
               %imshow(imgData)
               
               filter = {'*.png'};
               
               [filename,filepath] = uiputfile(filter); 
               imwrite(imgData,[filepath filename]);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.pHImageLabel = uilabel(app.UIFigure);
            app.pHImageLabel.Text = 'pH Image';
            app.LoadpHImageButton = uibutton(app.UIFigure, 'push');
            app.LoadpHImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpHImageButtonPushed, true);
            app.LoadpHImageButton.Text = 'Load pH Image';
            app.SemanticSegmentationButton = uibutton(app.UIFigure, 'push');
            app.SemanticSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SemanticSegmentationButtonPushed, true);
            app.SemanticSegmentationButton.Text = 'Semantic Segmentation';
            app.OverlayImageLabel = uilabel(app.UIFigure);
            app.OverlayImageLabel.Text = 'Overlay Image';
            app.ROIDetectionLabel = uilabel(app.UIFigure);
            app.ROIDetectionLabel.Text = 'ROI Detection';
            app.FinalOverlayButton = uibutton(app.UIFigure, 'push');
            app.FinalOverlayButton.ButtonPushedFcn = createCallbackFcn(app, @FinalOverlayButtonPushed, true);
            app.FinalOverlayButton.Text = {'Final Overlay'; ''};
            app.ChipTypeDropDownLabel = uilabel(app.UIFigure);
            app.ChipTypeDropDownLabel.Text = 'Chip Type :';
            app.ChipTypeDropDown = uidropdown(app.UIFigure);
            app.ChipTypeDropDown.Items = {'3D printed', 'Polycarbonate'};
            app.ChipTypeDropDown.Value = '3D printed';
            app.pHRangeintheDeviceLabel = uilabel(app.UIFigure);
            app.pHRangeintheDeviceLabel.Text = 'pH Range in the Device :';
            app.pHContoursButton = uibutton(app.UIFigure, 'push');
            app.pHContoursButton.ButtonPushedFcn = createCallbackFcn(app, @pHContoursButtonPushed, true);
            app.pHContoursButton.Text = {'pH Contours'; ''};
            app.CHANGLABUniversityofNotreDameLabel = uilabel(app.UIFigure);
            app.CHANGLABUniversityofNotreDameLabel.Text = {'CHANG LAB'; 'University of Notre Dame'; ''; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'cif logo.png';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, {''; ''})
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, {''; ''})
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, {''; ''})
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, {''; ''})
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.pHHeatmapLabel = uilabel(app.UIFigure);
            app.pHHeatmapLabel.Text = 'pH Heatmap';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'save-icon-32x32-0.jpg';
            app.Button.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 0.001;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 0.001;
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.CIFAnalyzerLabel = uilabel(app.UIFigure);
            app.CIFAnalyzerLabel.Text = 'CIF Analyzer';
            app.autoLabel = uilabel(app.UIFigure);
            app.autoLabel.Text = {'auto-'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CIF_analyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end